classdef FMapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        audiofiledirectoryEditFieldLabel  matlab.ui.control.Label
        audiofiledirectoryEditField  matlab.ui.control.EditField
        playoriginalButton           matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        specAxes                     matlab.ui.control.UIAxes
        syntimeAxes                  matlab.ui.control.UIAxes
        synfreqAxes                  matlab.ui.control.UIAxes
        envAxes                      matlab.ui.control.UIAxes
        modAxes                      matlab.ui.control.UIAxes
        audiofileDropDownLabel       matlab.ui.control.Label
        audiofileDropDown            matlab.ui.control.DropDown
        UITable                      matlab.ui.control.Table
        deleterowButton              matlab.ui.control.Button
        fftAxes                      matlab.ui.control.UIAxes
        synfftAxes                   matlab.ui.control.UIAxes
        continueButton               matlab.ui.control.Button
        fcEditFieldLabel             matlab.ui.control.Label
        fcEditField                  matlab.ui.control.NumericEditField
        fmEditFieldLabel             matlab.ui.control.Label
        fmEditField                  matlab.ui.control.NumericEditField
        playsynthButton              matlab.ui.control.Button
        tEditFieldLabel              matlab.ui.control.Label
        tEditField                   matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel      matlab.ui.control.Label
        amplitudeEditField           matlab.ui.control.NumericEditField
        resynthesizeButton           matlab.ui.control.Button
        f0EditFieldLabel             matlab.ui.control.Label
        f0EditField                  matlab.ui.control.NumericEditField
        midiEditFieldLabel           matlab.ui.control.Label
        midiEditField                matlab.ui.control.NumericEditField
        midiTable                    matlab.ui.control.Table
        reset                        matlab.ui.control.Button
    end

    
    properties (Access = private)
        window_len = 512;
        overlap_len = 128; 
        fs
        N
        t
        tn
        fn
        y1 % original audio
        amp
        x % normalized audio
        xx % envelope
        start
        endd
        xs
        Ns
        FCFM_indice
        FCFM
        PRMT % cell of parameter cells of different divisions
        fc_syn
        fm_syn
        mod_syn
        FM
        Niche
        f0  % f0 = fc/N1 = fm/N2
        N1
        N2
        h % h = 1 harmonic; h = 0 inharmonic
    end
    
    methods (Access = private)
        
        function xx = envelope(app)
            gap = 500;
            ind = floor(app.N/gap);
            env = [];
            pos = [];
            for i = 0:ind-1
                [M,I] = max(app.x(i*gap+1:(i+1)*gap));
                env = [env M];
                pos = [pos I+i*gap];
            end
            env = [0 env 0];
            pos = [0 pos app.N-1]/app.fs;
            xx = spline(pos,env,app.tn); % this is the amplitude envelope
            plot(app.envAxes,app.tn,xx)
           
        end
        
        function original(app,file)
            [y,app.fs] = audioread(file);
            [row,col] = size(y);
            if row > col
                y = y';
            end
            [~,app.N] = size(y);
            app.t = app.N/app.fs;
            app.tn = 0:1/app.fs:(app.N-1)/app.fs;
            app.fn = (0:1/app.N:1-1/app.N)*app.fs;
            
            app.y1 = y(1,:);
            app.amp = max(abs(app.y1));
            app.x = app.y1/app.amp;
            plot(app.UIAxes,app.tn,app.y1)
            [s,f,t1] = spectrogram(app.y1,app.window_len,app.overlap_len,app.window_len,app.fs);
            imagesc(app.specAxes, t1, f, abs(s));
            set(app.specAxes,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom
            ylim(app.specAxes,[0 app.fs/2]);
            c = colorbar(app.specAxes);
            c.Label.String = 'Magnitude(linear)';
            Y = fft(app.y1);
            app.fn = (0:1/app.N:1-1/app.N)*app.fs;
            plot(app.fftAxes,app.fn, abs(Y))
            xlim(app.fftAxes,[0 app.fs/2])
        end
        
        function parameter = para(app,div)
            % implement stft 
            % cut out the audio with significant amplitude
            
            bin = floor(app.Ns/div);
            F = zeros(div,bin);
            magF = zeros(div,bin);
            for i = 0:div-1
                s = app.xs(i*bin+1:(i+1)*bin);
                S = fft(s);
                F(i+1,:) = S;
                magF(i+1,:) = abs(S)/max(abs(S));
            end
            
            fc_index = zeros(1,div);
            freq_mag = cell(1,div);
            l = zeros(1,div);
            for i = 1:div
                fft_slice = magF(i,:);
                index = find(fft_slice > 0.1, 1); % level 0.1 for fc
                fc_index(i) = index * app.fs/bin;
                
                freq_i = find(fft_slice > 0.03)/bin*app.fs; % level 0.03 for significant frequency components
                magnitud = fft_slice(fft_slice > 0.03);
                frequencies = [];
                magnitudes = [];
                Mag = magnitud(1);
                Freq = freq_i(1);
                for j = 2:ceil(length(freq_i)/2)
                    if freq_i(j) - freq_i(j-1) < 50
                        if magnitud(j) > Mag
                            Mag = magnitud(j);
                            Freq = freq_i(j);
                        end
                    else
                        magnitudes = [magnitudes Mag];
                        frequencies = [frequencies Freq];
                        Mag = magnitud(j);
                        Freq = freq_i(j);
                    end
                end
                magnitudes = [magnitudes Mag];
                frequencies = [frequencies Freq];
                    
                l(i) = length(frequencies);
                freq_mag{i} = [frequencies; magnitudes];
            end
            
            fc_s = max(fc_index); % the smallest fc
            [m,i] = min(l);
            [M,I] = max(l);
            if m < 2
                fc_e = fc_s;
            else
                fc_e = freq_mag{i}(1,floor(m/2)); % the largest fc
            end
            fc_freq = freq_mag{I}(1,:);
            parameter = {};
            for j = 1:M
                if (fc_freq(j) > fc_s - 50) && (fc_freq(j) < fc_e + 50)
                    fc = fc_freq(j);
                    for k = j: ceil(M/2)       %floor(M/2)
                        fm = fc_freq(k+1) - fc_freq(j);
                        
                        for s = 0:4  % 0.03-0.08, 0.08-0.18, 0.18-0.28, 0.28-0.38, 0.38-0.48
                            parameter{end+1,1} = fc;
                            parameter{end,2} = fm;
                            upper = s*0.1+0.08;
                            lower = (s-1)*0.1+0.08;
                            for g = 1:div
                                up = find(freq_mag{g}(2,:) > upper,1,'last');
                                low = find(freq_mag{g}(2,:) > lower,1,'last');
                                if (freq_mag{g}(1,up) - fc) >= 0
                                    I_max = round((freq_mag{g}(1,low) - fc)/fm);
                                    I_min = round((freq_mag{g}(1,up) - fc)/fm);                          
                                    I_range = [I_min I_max];
                                    parameter{end,g+2} = I_range;
            
                                else
                                    parameter(end,:) = [];
                                    break
                                end
                            end
                        end
                    end
                end
            end
            
            parameter1 = cell2mat(parameter);
            [~,ia] = unique(parameter1, 'rows');
            parameter = parameter(ia,:);
            
            [n_fc, div_plus_2] = size(parameter);

            % test fc, fm
            jud = 7;
            niche = zeros(jud * n_fc, 3);
            for itte = 1:jud
                for i = 1:n_fc
                    fci = parameter{i,1};
                    fmi = parameter{i,2};
                    Index_y = zeros(1,div_plus_2 - 2);
                    for j = 3:div_plus_2
                        I_r = parameter{i,j};
                        randI = I_r(1) + rand * (I_r(2) - I_r(1));
                        Index_y(j-2) = randI;
                    end
                    % fit by 4th order polynomial
                    % time interval rand assignment
                    Index_x = zeros(1,div);
                    for k = 1:div
                        Index_x(k) = (app.start + (k-1) * bin + randi(bin))/app.fs;
                    end
                    xn = [app.start:app.endd]/app.fs;
                    modulation = spline(Index_x,Index_y,xn);
                    modulation = max(modulation,min(Index_y));
                    modulation = min(modulation,max(Index_y));
                    mod_full = [ones(1,app.start-1)*modulation(1) modulation ones(1,app.N-app.endd)*modulation(end)]; % modulation envelope
                    FM1 = app.xx.* cos(2*pi*fci*app.tn + mod_full.*cos(2*pi*fmi*app.tn));
               
            
                % implement stft for FM as for the original sound
                    FMs = FM1(app.start:app.endd);
                    F_syn = zeros(div,bin);
                    magF = zeros(div,bin);
                    for m = 0:div-1
                        s = app.xs(m*bin+1:(m+1)*bin);
                        S = fft(s);
                        magF(m+1,:) = abs(S);
                    end
                    magF_syn = zeros(div,bin);
                    for u = 0:div-1
                        s = FMs(u*bin+1:(u+1)*bin);
                        S = fft(s);
                        F_syn(u+1,:) = S;
                        magF_syn(u+1,:) = abs(S);
                    end
                    error = norm(magF-magF_syn, 'fro')/(bin*div);
                    row_num = i + (itte-1)*n_fc;
                    niche(row_num,1) = error;
                    niche(row_num,2) = fci;
                    niche(row_num,3) = fmi;
                end
            end
            
            E = niche(:,1);
            del = [];
            for i = 1 : n_fc-1
                group_i = i + [0:jud-1] * n_fc;
                for j = i+1 : n_fc
                    group_j = j + [0:jud-1] * n_fc;
                    w = E(group_i) < E(group_j);
                    if isempty(find(w == 0,1))
                        del = [del j];
                    elseif isempty(find(w == 1,1))
                        del = [del i];
                    end
                end
            end
            del = unique(del);
            parameter(del,:) = [];
        end
        
        function niche = itera(app,parameter)
            % start iteration
            [n_fc, div_plus_2] = size(parameter);
            iteration = 30;
            niche = ones(iteration * n_fc, 3+app.N)*100;
            [row,~] = size(app.FCFM);
            for ite = 1:iteration
                for i = 1:n_fc
                    fci = parameter{i,1};
                    fmi = parameter{i,2};
                    
                    for f = 1:row
                        if abs(app.FCFM(f,1)-fci) < 0.01 && abs(app.FCFM(f,2)-fmi) < 0.01
                            Index_y = zeros(1,div_plus_2 - 2);
                            for j = 3:div_plus_2
                                I_r = parameter{i,j};
                                randI = I_r(1) + rand * (I_r(2) - I_r(1));
                                Index_y(j-2) = randI;
                            end
                            % fit by spline
                            % time interval rand assignment
                            div = div_plus_2 - 2;
                            Index_x = zeros(1,div);
                            for k = 1:div
                                bin = floor(app.Ns/div);
                                Index_x(k) = (app.start + (k-1) * bin + randi(bin))/app.fs;
                            end
                            xn = [app.start:app.endd]/app.fs;
                            modulation = spline(Index_x,Index_y,xn);
                            modulation = max(modulation,min(Index_y));
                            modulation = min(modulation,max(Index_y));
                            mod_full = [ones(1,app.start-1)*modulation(1) modulation ones(1,app.N-app.endd)*modulation(end)]; % modulation envelope
                    
                            FM1 = app.xx.* cos(2*pi*fci*app.tn + mod_full.*cos(2*pi*fmi*app.tn));

                    
                        % implement stft for FM as for the original sound
                            FMs = FM1(app.start:app.endd);
                            F_syn = zeros(div,bin);
                            magF = zeros(div,bin);
                            for m = 0:div-1
                                s = app.xs(m*bin+1:(m+1)*bin);
                                S = fft(s);
                                magF(m+1,:) = abs(S);
                            end
                            magF_syn = zeros(div,bin);
                            for u = 0:div-1
                                s = FMs(u*bin+1:(u+1)*bin);
                                S = fft(s);
                                F_syn(u+1,:) = S;
                                magF_syn(u+1,:) = abs(S);
                            end
                            error = norm(magF-magF_syn, 'fro')/(bin*div);
                            row_num = i + (ite-1)*n_fc;
                            niche(row_num,1) = error;
                            niche(row_num,2) = fci;
                            niche(row_num,3) = fmi;
                            niche(row_num,4:end) = mod_full;
                            break;
                        end
                        
                    end
                end
                    
            end
        end
        
        function [f0,N1,N2,h] = fund(app,fc,fm) % f0 = fc/N1 = fm/N2
            h = 1;
            f0 = -1;
            if fc > fm
                ratio = fc/fm;
                if abs(round(ratio) - ratio) < 0.1
                    f0 = fm;
                    N1 = ratio;
                    N2 = 1;
                else
                    for i = 1:ceil(ratio)
                       fd = abs(fc - i * fm);
                       if abs(round(fm/fd) - fm/fd) < 0.1 && abs(round(fc/fd) - fc/fd) < 0.1 && gcd(round(fm/fd),round(fc/fd)) == 1
                           f0 = fd;
                           N1 = fc/fd;
                           N2 = fm/fd;
                       end
                    end
                    if f0 == -1
                        h = 0;
                        f0 = fm;
                        N1 = ratio;
                        N2 = 1;
                    end
                end
            else
                ratio = fm/fc;
                if abs(round(ratio) - ratio) < 0.1
                    f0 = fc;
                    N1 = 1;
                    N2 = ratio;
                else
                    for i = 1:ceil(ratio)
                       fd = abs(fc - i * fm);
                       if abs(round(fm/fd) - fm/fd) < 0.1 && abs(round(fc/fd) - fc/fd) < 0.1 && gcd(round(fm/fd),round(fc/fd)) == 1
                           f0 = fd;
                           N1 = fc/fd;
                           N2 = fm/fd;
                       end
                    end
                    if f0 == -1
                        h = 0;
                        f0 = fm;
                        N1 = 1;
                        N2 = ratio;
                    end
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.audiofileDropDown.Items = {'Instruments','bell',...
                'flute','clarinet','marimba','violin','viola','cello','saxophone','knocking'};
            app.midiTable.Data = {'C' 60 261.6326; 
                                    'Db' 61 277.1826; 
                                    'D' 62 293.6648; 
                                    'Eb' 63 311.1270; 
                                    'E' 64 329.6276; 
                                    'F' 65 349.2282; 
                                    'Gb' 66 369.9944;
                                    'G' 67 391.9954;
                                    'Ab' 68 415.3047; 
                                    'A' 69 440; 
                                    'Bb' 70 466.1638; 
                                    'B' 71 493.8833};
        end

        % Value changed function: audiofiledirectoryEditField
        function audiofiledirectoryEditFieldValueChanged(app, event)
            cla(app.UIAxes)
            cla(app.specAxes)
            cla(app.syntimeAxes)
            cla(app.synfreqAxes)
            cla(app.envAxes)
            cla(app.modAxes)
            cla(app.fftAxes)
            cla(app.synfftAxes)
            app.audiofileDropDown.Value = "Instruments";
            app.UITable.Data = [];
            app.fcEditField.Value = 0;
            app.fmEditField.Value = 0;
            app.tEditField.Value = 0;
            app.amplitudeEditField.Value = 0;
            app.f0EditField.Value = 0;
            app.midiEditField.Value = 0;
            pause(1)
            
        
            text = app.audiofiledirectoryEditField.Value;
            original(app,text)
            app.xx = envelope(app);
            
            level = 0.02;
            app.start = find(app.xx > level, 1);
            flipxx = fliplr(app.xx);
            app.endd = app.N - find(flipxx > 0.02, 1);
            app.xs = app.x(app.start:app.endd);
            app.Ns = length(app.xs);
            
            app.PRMT = cell(1,29); % 2-30 frame divisions
            app.FCFM = [];
            for i = 1:29
                parameter = para(app,i+1);
                app.PRMT{i} = parameter;
                [row,~] = size(parameter);
                for j = 1:row
                    fc = parameter{j,1};
                    fm = parameter{j,2};
                    app.FCFM = [app.FCFM; fc fm];
                end
            end
            app.FCFM = unique(app.FCFM,'rows');
            app.UITable.Data = app.FCFM;
        end

        % Button pushed function: playoriginalButton
        function playoriginalButtonPushed(app, event)
            sound(app.y1,app.fs)
%             audiowrite('sample\violin_original.wav',app.y1,app.fs)
        end

        % Value changed function: audiofileDropDown
        function audiofileDropDownValueChanged(app, event)
            cla(app.UIAxes)
            cla(app.specAxes)
            cla(app.syntimeAxes)
            cla(app.synfreqAxes)
            cla(app.envAxes)
            cla(app.modAxes)
            cla(app.fftAxes)
            cla(app.synfftAxes)
            app.audiofiledirectoryEditField.Value = "";
            app.UITable.Data = [];
            app.fcEditField.Value = 0;
            app.fmEditField.Value = 0;
            app.tEditField.Value = 0;
            app.amplitudeEditField.Value = 0;
            app.f0EditField.Value = 0;
            app.midiEditField.Value = 0;
            pause(1)
            
            switch app.audiofileDropDown.Value
                case 'bell'
                    dir = 'sample\bells.plastic.ff.G5.stereo.aif';
                case 'saxophone'
                    dir = 'sample\SopSax.nonvib.ff.G4.stereo.aif';
                case 'flute'
                    dir = 'sample\Flute.vib.ff.Ab4.stereo.aif';
                case 'clarinet'
                    dir = 'sample\BbClarinet.ff.B5.stereo.aif';
                case 'marimba'
                    dir = 'sample\Marimba.yarn.ff.B2.stereo.aif';
                case 'violin'
                    dir = 'sample\Violin.arco.ff.sulG.A4.stereo.aif';
                case 'viola'
                    dir = 'sample\Viola.arco.ff.sulC.Eb4.stereo.aif';
                case 'cello'
                    dir = 'sample\Cello.arco.ff.sulC.Db3.stereo.aif';
                case 'trumpet'
                    dir = 'sample\Trumpet.vib.ff.A3.stereo.aif';
                case 'knocking'
                    dir = 'sample\knocking.wav';
                    
            end
            original(app,dir)
            app.xx = envelope(app);
            
            level = 0.02;
            app.start = find(app.xx > level, 1);
            flipxx = fliplr(app.xx);
            app.endd = app.N - find(flipxx > 0.02, 1);
            app.xs = app.x(app.start:app.endd);
            app.Ns = length(app.xs);
            
            app.PRMT = cell(1,29); % 2-30 frame divisions
            app.FCFM = [];
            for i = 1:29
                parameter = para(app,i+1);
                app.PRMT{i} = parameter;
                [row,~] = size(parameter);
                for j = 1:row
                    fc = parameter{j,1};
                    fm = parameter{j,2};
                    app.FCFM = [app.FCFM; fc fm];
                end
            end
            app.FCFM = unique(app.FCFM,'rows');
            app.UITable.Data = app.FCFM;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.FCFM_indice = event.Indices(1);
        end

        % Button pushed function: deleterowButton
        function deleterowButtonPushed(app, event)
            app.UITable.Data(app.FCFM_indice,:) = [];
            app.FCFM = app.UITable.Data;
        end

        % Button pushed function: continueButton
        function continueButtonPushed(app, event)

            cla(app.syntimeAxes)
            cla(app.synfreqAxes)
            cla(app.modAxes)
            cla(app.synfftAxes)
            app.fcEditField.Value = 0;
            app.fmEditField.Value = 0;
            app.tEditField.Value = 0;
            app.amplitudeEditField.Value = 0;
            app.f0EditField.Value = 0;
            app.midiEditField.Value = 0;
            pause(1)
        
            app.Niche = [];
            for i = 1:20
                parameter = app.PRMT{i};
                niche = itera(app,parameter);
                app.Niche = [app.Niche; niche];
            end
            [~, ii] = min(app.Niche(:,1));
%             app.TextArea.Value = mat2str([min_err ii/30 app.Niche(100,1) app.Niche(200,1) app.Niche(ii+1,1)]);
            app.fc_syn = app.Niche(ii,2);
            app.fcEditField.Value = round(app.fc_syn,2);
            app.fm_syn = app.Niche(ii,3);
            app.fmEditField.Value = round(app.fm_syn,2);
            app.mod_syn = app.Niche(ii,4:end);
            plot(app.modAxes,app.tn, app.mod_syn)
            app.FM = app.amp * app.xx.* cos(2*pi*app.fc_syn*app.tn + app.mod_syn.*cos(2*pi*app.fm_syn*app.tn));
            
            plot(app.syntimeAxes,app.tn,app.FM)
            [s,f,t1] = spectrogram(app.FM,app.window_len,app.overlap_len,app.window_len,app.fs);
            imagesc(app.synfreqAxes, t1, f, abs(s));
            set(app.synfreqAxes,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom
            ylim(app.synfreqAxes,[0 app.fs/2]);
            c = colorbar(app.synfreqAxes);
            c.Label.String = 'Magnitude(linear)';
            Y = fft(app.FM);
            plot(app.synfftAxes,app.fn, abs(Y))
            xlim(app.synfftAxes,[0 app.fs/2])
            
            [app.f0,app.N1,app.N2,app.h] = fund(app,app.fc_syn,app.fm_syn);
            if app.h == 1
                app.f0EditField.Value = round(app.f0,2);
            end
            
            app.tEditField.Value = round(app.t,2);
            app.amplitudeEditField.Value = round(app.amp,2);
        
        end

        % Button pushed function: playsynthButton
        function playsynthButtonPushed(app, event)
            sound(app.FM,app.fs)
%             audiowrite('sample\knocking_syn_2.wav',app.FM,app.fs)
        end

        % Button pushed function: reset
        function resetPushed(app, event)
            app.fcEditField.Value = round(app.fc_syn,2);
            app.fmEditField.Value = round(app.fm_syn,2);
            app.tEditField.Value = round(app.t,2);
            app.amplitudeEditField.Value = round(app.amp,2);
            if app.h == 1
                app.f0EditField.Value = round(app.f0,2);
            end
            app.midiEditField.Value = 0;
        end

        % Value changed function: f0EditField
        function f0EditFieldValueChanged(app, event)
            ff0 = app.f0EditField.Value;
            app.fcEditField.Value = ff0 * app.N1;
            app.fmEditField.Value = ff0 * app.N2;
        end

        % Value changed function: midiEditField
        function midiEditFieldValueChanged(app, event)
            midi = app.midiEditField.Value;
            app.f0EditField.Value = round(440 * 2^((midi-69)/12),4);
            ff0 = app.f0EditField.Value;
            app.fcEditField.Value = ff0 * app.N1;
            app.fmEditField.Value = ff0 * app.N2;
        end

        % Button pushed function: resynthesizeButton
        function resynthesizeButtonPushed(app, event)
            rt = app.tEditField.Value;
            rN = rt * app.fs;
            mtn = linspace(0,(rN-1)/app.fs,app.N);
            rtn = 0:1/app.fs:(rN-1)/app.fs;
            rxx = interp1(mtn,app.xx,rtn,'spline');
            rmod = interp1(mtn,app.mod_syn,rtn,'spline');
            rFM = app.amplitudeEditField.Value * rxx.* cos(2*pi*app.fcEditField.Value*rtn + rmod.*cos(2*pi*app.fmEditField.Value*rtn));
            sound(rFM, app.fs)
%             audiowrite('sample\Trumpet_syn_inh.wav',rFM, app.fs)
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.audiofiledirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.audiofiledirectoryEditFieldLabel.Text = 'audio file directory';
            app.audiofiledirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.audiofiledirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @audiofiledirectoryEditFieldValueChanged, true);
            app.playoriginalButton = uibutton(app.UIFigure, 'push');
            app.playoriginalButton.ButtonPushedFcn = createCallbackFcn(app, @playoriginalButtonPushed, true);
            app.playoriginalButton.Text = 'play (original)';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Sound (waveform)')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Magnitude (linear)')
            app.specAxes = uiaxes(app.UIFigure);
            title(app.specAxes, 'Original Sound (spectrogram)')
            xlabel(app.specAxes, 'Time (s)')
            ylabel(app.specAxes, 'Frequency (Hz)')
            app.syntimeAxes = uiaxes(app.UIFigure);
            title(app.syntimeAxes, 'FM Synth Sound (waveform)')
            xlabel(app.syntimeAxes, 'Time (s)')
            ylabel(app.syntimeAxes, 'Magnitude (linear)')
            app.synfreqAxes = uiaxes(app.UIFigure);
            title(app.synfreqAxes, 'FM Synth Sound (spectrogram)')
            xlabel(app.synfreqAxes, 'Time (s)')
            ylabel(app.synfreqAxes, 'Frequency (Hz)')
            app.envAxes = uiaxes(app.UIFigure);
            title(app.envAxes, 'Amplitude Envelope')
            xlabel(app.envAxes, 'Time (s)')
            ylabel(app.envAxes, 'Normalized Magnitude')
            app.modAxes = uiaxes(app.UIFigure);
            title(app.modAxes, 'Modulation Envelope')
            xlabel(app.modAxes, 'Time (s)')
            ylabel(app.modAxes, 'Modulation Index')
            app.audiofileDropDownLabel = uilabel(app.UIFigure);
            app.audiofileDropDownLabel.Text = 'audio file';
            app.audiofileDropDown = uidropdown(app.UIFigure);
            app.audiofileDropDown.ValueChangedFcn = createCallbackFcn(app, @audiofileDropDownValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'fc'; 'fm'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.deleterowButton = uibutton(app.UIFigure, 'push');
            app.deleterowButton.ButtonPushedFcn = createCallbackFcn(app, @deleterowButtonPushed, true);
            app.deleterowButton.Text = 'delete row';
            app.fftAxes = uiaxes(app.UIFigure);
            title(app.fftAxes, 'Original Sound (fft)')
            xlabel(app.fftAxes, 'Frequency (Hz)')
            ylabel(app.fftAxes, 'Magnitude (linear)')
            app.synfftAxes = uiaxes(app.UIFigure);
            title(app.synfftAxes, 'FM Synth Sound (fft)')
            xlabel(app.synfftAxes, 'Frequency (Hz)')
            ylabel(app.synfftAxes, 'Magnitude (linear)')
            app.continueButton = uibutton(app.UIFigure, 'push');
            app.continueButton.ButtonPushedFcn = createCallbackFcn(app, @continueButtonPushed, true);
            app.continueButton.Text = 'continue';
            app.fcEditFieldLabel = uilabel(app.UIFigure);
            app.fcEditFieldLabel.Text = 'fc';
            app.fcEditField = uieditfield(app.UIFigure, 'numeric');
            app.fmEditFieldLabel = uilabel(app.UIFigure);
            app.fmEditFieldLabel.Text = 'fm';
            app.fmEditField = uieditfield(app.UIFigure, 'numeric');
            app.playsynthButton = uibutton(app.UIFigure, 'push');
            app.playsynthButton.ButtonPushedFcn = createCallbackFcn(app, @playsynthButtonPushed, true);
            app.playsynthButton.Text = 'play (synth)';
            app.tEditFieldLabel = uilabel(app.UIFigure);
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.UIFigure, 'numeric');
            app.amplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.amplitudeEditFieldLabel.Text = 'amplitude';
            app.amplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.resynthesizeButton = uibutton(app.UIFigure, 'push');
            app.resynthesizeButton.ButtonPushedFcn = createCallbackFcn(app, @resynthesizeButtonPushed, true);
            app.resynthesizeButton.Text = 'resynthesize';
            app.f0EditFieldLabel = uilabel(app.UIFigure);
            app.f0EditFieldLabel.Text = 'f0';
            app.f0EditField = uieditfield(app.UIFigure, 'numeric');
            app.f0EditField.ValueChangedFcn = createCallbackFcn(app, @f0EditFieldValueChanged, true);
            app.midiEditFieldLabel = uilabel(app.UIFigure);
            app.midiEditFieldLabel.Text = 'midi';
            app.midiEditField = uieditfield(app.UIFigure, 'numeric');
            app.midiEditField.ValueChangedFcn = createCallbackFcn(app, @midiEditFieldValueChanged, true);
            app.midiTable = uitable(app.UIFigure);
            app.midiTable.ColumnName = {'Note'; 'MIDI'; 'Frequency'};
            app.midiTable.RowName = {};
            app.midiTable.ColumnEditable = false;
            app.reset = uibutton(app.UIFigure, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetPushed, true);
            app.reset.Icon = 'img_178086.png';
            app.reset.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FMapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end